classdef IPPR_Glove_Defect_Detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        StartTab                    matlab.ui.container.Tab
        GloveDefectDetectionSystemLabel  matlab.ui.control.Label
        ExitApplicationButton       matlab.ui.control.Button
        UploadImageButton           matlab.ui.control.Button
        PreProcessingTab            matlab.ui.container.Tab
        BinariseCheckBox            matlab.ui.control.CheckBox
        Label_3                     matlab.ui.control.Label
        GaussianBlurCheckBox        matlab.ui.control.CheckBox
        Label_2                     matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        ConverttoGrayscaleCheckBox  matlab.ui.control.CheckBox
        PreProcessingStepsLabel     matlab.ui.control.Label
        PreProcessingPanel          matlab.ui.container.Panel
        Segmentation                matlab.ui.container.Tab
    end

    
    properties (Access = private)
        uploadedImagesFileName % Uploaded Images File Names
        uploadedImagesPath % Uploaded Images Path
        subplotRow % Number of rows in panel subplot
     end

    
    methods (Access = private)
        
        function img = ImgResizeGrayscale(~, imgPath)
            img = imread(imgPath);
            
            % Convert to Grayscale
            img = im2gray(img);

            % Resize image
            img = imresize(img, [480, 480]);
        
        end

        function img = ImgGaussianBlur(app, imgPath)
            img = ImgResizeGrayscale(app, imgPath);
            
            % Gaussian Blur
            img = imgaussfilt(img, 3);        
        end

        function img = ImgBinarise(app, imgPath)
           imgBlur = ImgGaussianBlur(app, imgPath);
            
            % Otsu's Method
            counts = imhist(imgBlur,10);
            thresholdValue = otsuthresh(counts);

            % Convert to Binary
            img = imbinarize(imgBlur, thresholdValue);
            
        end

        function img = ImgPreProcess(app, imgPath)                       
            
            img = ImgBinarise(app, imgPath);

        end

        function img = ImgSegmentation(app, imgPath)
%             img = imread(imgPath);

            img = ImgPreProcess(app, imgPath);
%             img = uint8(img);
%             img = imsegkmeans(img, 1);

%             [labeledImage, numberOfRegions] = bwlabel(img);
%             props = regionprops(labeledImage, 'MajorAxisLength', 'Orientation');

              B = bwboundaries(img);

%             [B,L] = bwboundaries(img,'noholes');
%             imshow(label2rgb(L, @jet, [.5 .5 .5]))
%             hold on
%             for k = 1:length(B)
%                boundary = B{k};
%                plot(boundary(:,2), boundary(:,1), 'w', 'LineWidth', 2)
%             end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
     
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            
            % Get Images
            [file,path] = uigetfile("*.png; *.jpg; *.jpeg; *.tif; *.gif", "Image Files", "MultiSelect","on");
            file = cellstr(file);
            if length(file) > 1
                for i = 1:length(file)
                    x = fullfile(path, file{i});
                    app.uploadedImagesPath{i} = x;
                    app.uploadedImagesFileName{i} = file{i};
%                     disp(x);
%                     img = imread(x);
%                     figure(i);
%                     imshow(img);
                end
            elseif length(file) == 1
                    x = fullfile(path, file{1});
                    app.uploadedImagesPath{1} = x;
                   app.uploadedImagesFileName{1} = file{1};
            else
                disp("Error");
            end
            
            app.subplotRow = ceil(length(app.uploadedImagesPath) / 3);
            delete(f); %delete the dummy figure

            % Change tabs
            app.TabGroup.SelectedTab = app.PreProcessingTab;
            app.TabGroupSelectionChanged();

%             files = fullfile(path, file);
%             x = imread(files);
%             imshow(x);
%            disp("Path ", path);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % Start Page - clear values
            if strcmp(app.TabGroup.SelectedTab.Title, app.StartTab.Title)
                app.uploadedImagesFileName(:) = [];
                app.uploadedImagesPath(:) = [];
                app.subplotRow = 0;
                
                clc;               
            end

            % Preprocessing
            if strcmp(app.TabGroup.SelectedTab.Title, app.PreProcessingTab.Title)
                
                % Obtain Panel location
%                 ax = uiaxes(app.PreProcessingPanel);

                % Perform Pre-Processing 
%                 subplotCol = ceil(length(app.uploadedImagesPath) / 3 + 1);
%                 subplotRow = ceil(length(app.uploadedImagesPath) / 3);
                
                delete(app.PreProcessingPanel);
                app.PreProcessingPanel = uipanel("AutoResizeChildren", "off", Title="", Position=[242,1,627,563], Parent=app.PreProcessingTab);
                
                for i = 1:length(app.uploadedImagesPath)
                    img = ImgPreProcess(app, app.uploadedImagesPath{i});
                    ax = subplot(app.subplotRow, 3, i, 'Parent', app.PreProcessingPanel);
                    imshow(img, 'Parent', ax);
                end
               
                app.ConverttoGrayscaleCheckBox.Value = 1;
                app.GaussianBlurCheckBox.Value = 1;
                app.BinariseCheckBox.Value = 1;
            end 
            
            % Segmentation
            if strcmp(app.TabGroup.SelectedTab.Title, app.Segmentation.Title)
%                 delete(app.Segmentation);
%                 app.Segmentation = uipanel("AutoResizeChildren", "off", Title="", Position=[242,1,627,563], Parent=app.Segmentation);

                for i = 1:length(app.uploadedImagesPath)
                    img = ImgPreProcess(app, app.uploadedImagesPath{i});
%                     img = ImgSegmentation(app, app.uploadedImagesPath{i});
%                     img = ImgSegmentation(app, app.uploadedImagesPath{i});
                    
%                     img = bwboundaries(img);
                    
%                     se = strel('disk', 5);
%                     se2 = strel('line', 10, 45);
%                     img = imclose(img, se);
%                     img = imclose(img, se2);
                    
%                     [B,L] = bwboundaries(img);
% 
                    ax = subplot(app.subplotRow, 3, i, 'Parent', app.Segmentation);
%                     ax2 = subplot(2, length(app.uploadedImagesPath), i+length(app.uploadedImagesPath), 'Parent', app.Segmentation);
%                     ax = subplot(app.subplotRow, 3, i, 'Parent', app.Segmentation);
% %                     imshow(img, 'Parent', ax);
% 
%                     imshow(label2rgb(L, @jet, [.5 .5 .5]), 'Parent', ax)
% %                     hold on
% %                     for k = 1:length(B)
% %                        boundary = B{k};
% %                        plot(boundary(:,2), boundary(:,1), 'w', 'LineWidth', 2)
% %                     end

                    hole = xor(img,imfill(img,'holes'));
%                     img = double(img);
%                     img(hole) = 2;
%                     x = label2rgb(img);
%                     disp(x);
%                     img = img(hole);
%                     hole = xor(img,imfill(img,'holes'));
%                     img = double(img);
%                     img(hole) = 1;
%                     imshow(label2rgb(img), 'Parent', ax);
%                      
                     imshow(hole, 'Parent', ax);
%                      disp(regionprops(hole, 'Area'));
% 
% %                      [c, h] = imcontours(hole);
% %                      approx = 
% %                     imshow(bwboundaries(uint8(hole)), 'Parent', ax);
% 
% %                     approxCurve = cv.approxPolyDP(curve);
% %                     disp(approxCurve);
% 
% %                     hole = smooth2a(imagesc(hole),10,10);
% %                      imshow((hole), 'Parent', ax);
%                     
%                     % Extract the perimeter of the segment
%                     perim = bwperim(hole);
%                     
%                     % Define a smoothing filter
%                     smoothingFilter = fspecial('gaussian', [5 5], 2);
%                     
%                     % Smooth the perimeter
%                     smoothPerim = imfilter(double(perim), smoothingFilter);
%                     
%                     % Convert the smoothed perimeter back to binary
%                     smoothBinaryImage = imbinarize(smoothPerim);
%                     imshow(smoothBinaryImage, 'Parent', ax2);

                end

%                 img  = app.uploadedImagesPath;
%                 seg = imsegkmeans(img, 3);
% %                 mshow(img, 'Parent', ax);
               
            end
        end

        % Value changed function: ConverttoGrayscaleCheckBox
        function ConverttoGrayscaleCheckBoxValueChanged(app, event)
            value = app.ConverttoGrayscaleCheckBox.Value;
%             subplotRow = ceil(length(app.uploadedImagesPath) / 3);
            
            app.GaussianBlurCheckBox.Value = 0;
            app.BinariseCheckBox.Value = 0;

            if (value) == 1
                for i = 1:length(app.uploadedImagesPath)
                    img = ImgResizeGrayscale(app, app.uploadedImagesPath{i});
                    ax = subplot(app.subplotRow, 3, i, 'Parent', app.PreProcessingPanel);
                    imshow(img, 'Parent', ax);
                end
            else
                for i = 1:length(app.uploadedImagesPath)
                    img = app.uploadedImagesPath{i};
                    ax = subplot(app.subplotRow, 3, i, 'Parent', app.PreProcessingPanel);
                    imshow(img, 'Parent', ax);
                end
            end 
        end

        % Value changed function: GaussianBlurCheckBox
        function GaussianBlurCheckBoxValueChanged(app, event)
            value = app.GaussianBlurCheckBox.Value;
            
            app.BinariseCheckBox.Value = 0;
            app.ConverttoGrayscaleCheckBox.Value = 1;
            
            if (value) == 1
                for i = 1:length(app.uploadedImagesPath)
                    img = ImgGaussianBlur(app, app.uploadedImagesPath{i});
                    ax = subplot(app.subplotRow, 3, i, 'Parent', app.PreProcessingPanel);
                    imshow(img, 'Parent', ax);
                end
            else
                for i = 1:length(app.uploadedImagesPath)
                    img = ImgResizeGrayscale(app, app.uploadedImagesPath{i});
                    ax = subplot(app.subplotRow, 3, i, 'Parent', app.PreProcessingPanel);
                    imshow(img, 'Parent', ax);
                end
            end             
        end

        % Value changed function: BinariseCheckBox
        function BinariseCheckBoxValueChanged(app, event)
            value = app.BinariseCheckBox.Value;

            app.ConverttoGrayscaleCheckBox.Value = 1;
            app.GaussianBlurCheckBox.Value = 1;

            if (value) == 1
                for i = 1:length(app.uploadedImagesPath)
                    img = ImgBinarise(app, app.uploadedImagesPath{i});
                    ax = subplot(app.subplotRow, 3, i, 'Parent', app.PreProcessingPanel);
                    imshow(img, 'Parent', ax);
                end
            else
                for i = 1:length(app.uploadedImagesPath)
                    img = ImgGaussianBlur(app, app.uploadedImagesPath{i});
                    ax = subplot(app.subplotRow, 3, i, 'Parent', app.PreProcessingPanel);
                    imshow(img, 'Parent', ax);
                end
            end           
        end

        % Button pushed function: ExitApplicationButton
        function ExitApplicationButtonPushed(app, event)
           delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = 'Start';
            app.UploadImageButton = uibutton(app.StartTab, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.ExitApplicationButton = uibutton(app.StartTab, 'push');
            app.ExitApplicationButton.ButtonPushedFcn = createCallbackFcn(app, @ExitApplicationButtonPushed, true);
            app.ExitApplicationButton.Text = 'Exit Application';
            app.GloveDefectDetectionSystemLabel = uilabel(app.StartTab);
            app.GloveDefectDetectionSystemLabel.Text = 'Glove Defect Detection System';
            app.PreProcessingTab = uitab(app.TabGroup);
            app.PreProcessingTab.Title = 'Pre-Processing';
            app.PreProcessingPanel = uipanel(app.PreProcessingTab);
            app.PreProcessingStepsLabel = uilabel(app.PreProcessingTab);
            app.PreProcessingStepsLabel.Text = 'Pre-Processing Steps';
            app.ConverttoGrayscaleCheckBox = uicheckbox(app.PreProcessingTab);
            app.ConverttoGrayscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConverttoGrayscaleCheckBoxValueChanged, true);
            app.ConverttoGrayscaleCheckBox.Text = 'Convert to Grayscale';
            app.ConverttoGrayscaleCheckBox.Value = true;
            app.Label = uilabel(app.PreProcessingTab);
            app.Label.Text = '1.';
            app.Label_2 = uilabel(app.PreProcessingTab);
            app.Label_2.Text = '2.';
            app.GaussianBlurCheckBox = uicheckbox(app.PreProcessingTab);
            app.GaussianBlurCheckBox.ValueChangedFcn = createCallbackFcn(app, @GaussianBlurCheckBoxValueChanged, true);
            app.GaussianBlurCheckBox.Text = 'Gaussian Blur';
            app.GaussianBlurCheckBox.Value = true;
            app.Label_3 = uilabel(app.PreProcessingTab);
            app.Label_3.Text = '3.';
            app.BinariseCheckBox = uicheckbox(app.PreProcessingTab);
            app.BinariseCheckBox.ValueChangedFcn = createCallbackFcn(app, @BinariseCheckBoxValueChanged, true);
            app.BinariseCheckBox.Text = 'Binarise';
            app.BinariseCheckBox.Value = true;
            app.Segmentation = uitab(app.TabGroup);
            app.Segmentation.Title = 'Segmentation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IPPR_Glove_Defect_Detection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end